classdef ISAR_QLP < matlab.apps.AppBase
    %ISAR_QLP
    %   If: Invalid field values have been selected. Please ensure that you
    %   have:
    %   1. Select the HRR profiles as a field containing a matrix of
    %   dimension: number of pulses x number of bursts (pulse trains).
    %   2. Select the PRI as a field containing the pulse repetition
    %   interval in seconds.
    %   3. Select the Downrange Axis as a field containing an array of size
    %   = number of bursts (pulse trains). Ideally this is just an array
    %   with values from 0 to number of bursts * range resolution.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PausePlayback             matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        ParametersPanel           matlab.ui.container.Panel
        OverlapEditField          matlab.ui.control.NumericEditField
        OverlapEditFieldLabel     matlab.ui.control.Label
        RangeAxisDropDown         matlab.ui.control.DropDown
        HRRProfilesLabel_3        matlab.ui.control.Label
        PRIDropDown               matlab.ui.control.DropDown
        HRRProfilesLabel_2        matlab.ui.control.Label
        HRRDropDown               matlab.ui.control.DropDown
        HRRProfilesLabel          matlab.ui.control.Label
        FilePathEditField         matlab.ui.control.EditField
        FilePathEditFieldLabel    matlab.ui.control.Label
        LoadFileButton            matlab.ui.control.Button
        CPIEditField              matlab.ui.control.NumericEditField
        CPIEditFieldLabel         matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        PlaybackspeedButtonGroup  matlab.ui.container.ButtonGroup
        x2Button                  matlab.ui.control.ToggleButton
        x15Button                 matlab.ui.control.ToggleButton
        x1Button                  matlab.ui.control.ToggleButton
        x05Button                 matlab.ui.control.ToggleButton
        PlayVideoButton           matlab.ui.control.Button
        GenerateButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data_file_path = "test.mat";
        video_file_path = "ISARmovie"; % Default video name
        video_file_path_full="ISARmovie.avi"; 
        images_generated_flag = false; % Flag to track if images have been generated
        playback_speed = 0.1;  % Default speed value
        cancel_flag = false;
        is_paused_flag = false; % Flag to track whether the video is paused
        
        structure = -1;
        cptwl = 128;    % Frame length
        overlap_percentage = 50; % Frame overlap percentage
        num_frames=0

        HRRProfiles_all = -1;
        PRI = -1;
        Range_Axis = -1;
       
    end
    
    methods (Access = private)

        
        function fieldNames = getAllFieldNames(app,structure)
            % Initialize an empty cell array to store field names
            fieldNames = {};

            % Get the field names of the current structure
            currentFieldNames = fieldnames(structure);

            % Loop through each field
            for i = 1:numel(currentFieldNames)
                fieldName = currentFieldNames{i};

                % Check if the field contains a substructure (nested structure)
                if isstruct(structure.(fieldName))
                    % Recursively get the field names from the substructure
                    subFieldNames = getAllFieldNames(app,structure.(fieldName));

                    % Add the current field name as a prefix to subfield names
                    subFieldNames = strcat(fieldName, '.', subFieldNames);

                    % Add subfield names to the main list of field names
                    fieldNames = [fieldNames; subFieldNames];
                else
                    % If the field does not contain a substructure, add it to the list
                    fieldNames = [fieldNames; fieldName];
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initially disable buttons
            app.GenerateButton.Enable = 'off';
            app.PausePlayback.Enable = 'off';
            app.CancelButton.Enable = 'off';
            app.PlayVideoButton.Enable = 'off'; 
            app.x2Button.Enable = 'off';
            app.x15Button.Enable = 'off';
            app.x1Button.Enable = 'off';
            app.x05Button.Enable = 'off';

            % Set x1Button as the default selected button
            app.x1Button.Value = true;
            
            % Initially disable
            app.HRRDropDown.Enable = "off";
            app.PRIDropDown.Enable = "off";
            app.RangeAxisDropDown.Enable = "off";
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Enable Cancel button
            app.CancelButton.Enable = 'on';
            app.PausePlayback.Enable = 'off';
            app.cancel_flag = false;
            app.is_paused_flag = false;
            app.Image.Visible = 'off';
            % Disable the buttons
            app.GenerateButton.Enable = 'off';
            app.PlayVideoButton.Enable = 'off';
            app.x2Button.Enable = 'off';
            app.x15Button.Enable = 'off';
            app.x1Button.Enable = 'off';
            app.x05Button.Enable = 'off';
     
            try
            % Get parameters
            app.HRRProfiles_all = app.HRRProfiles_all(1:end,:); % adjust to data size wanted
            profile_repetition_freq =  1/app.PRI;
            
            [num_range_bins,~] = size(app.HRRProfiles_all);

            % Loading message
            d = uiprogressdlg(app.UIFigure,'Title','Generating Video',...
                'Indeterminate','on','Cancelable','on');
            drawnow
            
           
                %% 3 Slide window through all profiles (create frames)
                % Step 1: Define parameters
                overlap = app.overlap_percentage/100 * app.cptwl;

                % Step 2: Calculate the number of frames
                app.num_frames = floor(1 + (num_range_bins-app.cptwl)/(app.cptwl-overlap));

                % Create video object
                vidObj = VideoWriter(app.video_file_path,'Uncompressed AVI');
                vidObj.FrameRate = 3;
                open(vidObj);

                figure;
                set(gcf, 'Visible', 'off')

                % Step 3: Get all unfocused frames
                for frame = 1: app.num_frames
                    % Check if generation should be canceled
                    if d.CancelRequested
                        break; % Exit the loop if canceled
                    end

                    start_profile = floor((frame-1) * (app.cptwl-overlap) + 1 );
                    stop_profile = floor(start_profile + app.cptwl-1);
                    profiles_to_process = stop_profile - start_profile;
                    DopplerAxis_Hz = (-profiles_to_process/2:1:profiles_to_process/2-1)*profile_repetition_freq/profiles_to_process;

                    %% 3.1 Unfocused HRRPs and ISAR image - no range alignment, no autofocus
                    % Plot Unaligned HRR Profiles (single frame)
                    HRR_profiles = circshift(app.HRRProfiles_all(start_profile:stop_profile, :), [0 50]);

                    %% 3.2 Range-aligned HRRPs and ISAR image - no autofocus
                    % Range-align the HRR profiles
                    ref_profile_number = 1; % arbitrarily selected
                    RA_HRR_profiles = HaywoodRA(HRR_profiles,ref_profile_number);

                    %% 3.3 Autofocus Profiles
                    % Autofocus the HRR profiles
                    AF_RA_HRR_profiles = YuanAF(RA_HRR_profiles);

                    % Plot focused ISAR image
                    % apply hamming window function
                    [num_profiles,num_range_bins] = size(RA_HRR_profiles);
                    window = repmat(hamming(num_profiles),1,num_range_bins);

                    ISAR_image = fftshift(fft(AF_RA_HRR_profiles.*window,[],1),1); % Apply FFT in the slow-time dimension

                    ISAR_image_dB = Normalise_limitDynamicRange_ISAR_dB(ISAR_image, 35);    % Limit dynamic range of ISAR image

                    contrast_focused = imageContrast(ISAR_image);

                    %% Plot ISAR image
                    cla;
                    imagesc(app.Range_Axis, DopplerAxis_Hz, ISAR_image_dB);
                    xlabel('Range(m)'); ylabel('Doppler frequency (Hz)');
                    title(['ISAR ' num2str(frame) ' of ' num2str(app.num_frames)  ' - C = ' num2str(contrast_focused)]);
                    axis xy; colormap('jet'); colorbar;

                    hold on;
                    frame_content = getframe(gcf);
                    writeVideo(vidObj, frame_content);
                    hold off;
                end

                % Clean up the VideoReader object
                close(vidObj);
                delete(vidObj);

                close(d);
            
            catch ME
                uialert(app.UIFigure, ['Invalid field values have been selected. ' ...
                        'Please ensure that you have: ' ...
                        '1. Select the HRR profiles as a field containing ' ...
                        'a matrix of dimension: number of pulses x number of bursts (pulse trains).' ...
                        '2. Select the PRI as a field containing the pulse repetition interval ' ...
                        'in seconds.' ...
                        '3. Select the Downrange Axis as a field containing an ' ...
                        'array of size = number of bursts (pulse trains).' ...
                        'Ideally this is just an array with values from 0 ' ...
                        'to number of bursts * range resolution.' ...
                        ''], 'Error', 'Icon', 'error');
                app.GenerateButton.Enable = 'off';
                 
            end
            % Set the flag to indicate that images have been generated
            app.images_generated_flag = true;
    
            % Enable the buttons
            app.GenerateButton.Enable = 'on';
            app.x2Button.Enable = 'on';
            app.x15Button.Enable = 'on';
            app.x1Button.Enable = 'on';
            app.x05Button.Enable = 'on';
            app.PlayVideoButton.Enable = 'on';
            
            % Disable Cancel button
            app.CancelButton.Enable = 'off';
          
        end

        % Button pushed function: PlayVideoButton
        function ReplayButtonPushed(app, event)
            % Enable pause
            app.PausePlayback.Enable = 'on';
            app.CancelButton.Enable = 'on';
            app.cancel_flag = false;
            app.is_paused_flag = false;
            app.Image.Visible = 'on';
            % Disable the buttons
            app.GenerateButton.Enable = 'off';
            app.PlayVideoButton.Enable = 'off';
            app.x2Button.Enable = 'off';
            app.x15Button.Enable = 'off';
            app.x1Button.Enable = 'off';
            app.x05Button.Enable = 'off';

            % Check if the video file exists
            if ~exist(app.video_file_path_full, 'file')
                uialert(app.UIFigure, 'Video file does not exist!', 'Error', 'Icon', 'error');
                return;
            end
           
            % Create a VideoReader object
            vidObj = VideoReader(app.video_file_path_full);
            % Read and display video frames
            while hasFrame(vidObj)
                %Check if playback should be canceled
                if app.cancel_flag
                    break; % Exit the loop if canceled
                elseif app.is_paused_flag
                    % If paused, don't read new frames, just stay on the current frame
                    pause(0.1);
                else
                    frame = readFrame(vidObj);
                    app.Image.ImageSource = frame;
                    pause(1 * app.playback_speed)
                    
                end
            end
        
            % Clean up the VideoReader object
            delete(vidObj);

            % Enable the buttons
            app.x2Button.Enable = 'on';
            app.x15Button.Enable = 'on';
            app.x1Button.Enable = 'on';
            app.x05Button.Enable = 'on';
            app.PlayVideoButton.Enable = 'on';
            app.GenerateButton.Enable = 'on';
            % Disable pause
            app.PausePlayback.Enable = 'off';
            app.CancelButton.Enable = 'off';
        end

        % Selection changed function: PlaybackspeedButtonGroup
        function SpeedButtonPushed(app, event)
            % Determine which speed button is pressed and set the global 
            % speed variable accordingly
            if app.x05Button.Value
                app.playback_speed = 1;
            elseif app.x1Button.Value
                app.playback_speed = 0.5;
            elseif app.x15Button.Value
                app.playback_speed = 0.1;
            elseif app.x2Button.Value
                app.playback_speed = 0.005;
            end
        end

        % Callback function: FilePathEditField, FilePathEditField
        function DataFilePathChanged(app, event)

             % Get the entered file path
            app.data_file_path = app.FilePathEditField.Value;
        
            % Check if the entered file path is valid
            if ~exist(app.data_file_path, 'file')
                % Display an error message
                uialert(app.UIFigure, 'Invalid file name.', 'Error', 'Icon', 'error');
                % Disable the Generate button
                app.GenerateButton.Enable = 'off';
            else
                % Enable the Generate button
                app.GenerateButton.Enable = 'on';
            end
            
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.cancel_flag = true;
            app.is_paused_flag = false;
            app.PausePlayback.Icon = "icon_pause.png"; % Update button icon
        end

        % Button pushed function: PausePlayback
        function PausePlaybackButtonPushed(app, event)
            app.is_paused_flag = ~app.is_paused_flag; % Toggle the IsPaused flag
            if app.is_paused_flag
                app.PausePlayback.Icon = "icon_play.png"; % Update button icon
            else
                app.PausePlayback.Icon = "icon_pause.png"; % Update button icon
            end
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [app.data_file_path,file] = uigetfile;
            app.FilePathEditField.Value = app.data_file_path;
            % Load data file
            app.structure = load(app.data_file_path);

            if(file~=0)
                
                % Get all field names, subfield names, and subfilenames
                allFieldNames = getAllFieldNames(app,app.structure);
                app.HRRDropDown.Items = allFieldNames;
                app.PRIDropDown.Items = allFieldNames;
                app.RangeAxisDropDown.Items = allFieldNames;
            
                % Enable the Generate button
                app.HRRDropDown.Enable = "on";
                app.PRIDropDown.Enable = "off";
                app.RangeAxisDropDown.Enable = "off";
                app.GenerateButton.Enable = 'off';

            end

        end

        % Value changed function: CPIEditField
        function CPIEditFieldValueChanged(app, event)
            app.cptwl = app.CPIEditField.Value;

        end

        % Value changed function: OverlapEditField
        function OverlapEditFieldValueChanged(app, event)
            app.overlap_percentage = app.OverlapEditField.Value;
       
        end

        % Value changed function: HRRDropDown
        function HRRDropDownValueChanged(app, event)
            value = app.HRRDropDown.Value;
            fields = strsplit(value, '.');
            nestedStructure = app.structure;
            % Loop through the fields to access the desired field
            for i = 1:numel(fields)
                nestedField = fields{i};
                if isfield(nestedStructure, nestedField)
                    nestedStructure = nestedStructure.(nestedField);
                else
                    % Handle the case where the field does not exist
                    % You can display an error message or take appropriate action
                end
            end
            app.HRRProfiles_all = (nestedStructure)';
            app.PRIDropDown.Enable = "on";
                
        end

        % Value changed function: PRIDropDown
        function PRIDropDownValueChanged(app, event)
            value = app.PRIDropDown.Value;
            fields = strsplit(value, '.');
            nestedStructure = app.structure;
            % Loop through the fields to access the desired field
            for i = 1:numel(fields)
                nestedField = fields{i};
                if isfield(nestedStructure, nestedField)
                    nestedStructure = nestedStructure.(nestedField);
                else
                    % Handle the case where the field does not exist
                    % You can display an error message or take appropriate action
                end
            end
            app.PRI =  nestedStructure;
            app.RangeAxisDropDown.Enable = "on";
        end

        % Value changed function: RangeAxisDropDown
        function RangeAxisDropDownValueChanged(app, event)
            value = app.RangeAxisDropDown.Value;
            fields = strsplit(value, '.');
            nestedStructure = app.structure;
            % Loop through the fields to access the desired field
            for i = 1:numel(fields)
                nestedField = fields{i};
                if isfield(nestedStructure, nestedField)
                    nestedStructure = nestedStructure.(nestedField);
                else
                    % Handle the case where the field does not exist
                    % You can display an error message or take appropriate action
                end
            end
            app.Range_Axis= nestedStructure;
            app.GenerateButton.Enable = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {547, 547};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.PlayVideoButton = uibutton(app.LeftPanel, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.PlayVideoButton.Text = 'Play Video';
            app.PlaybackspeedButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlaybackspeedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.PlaybackspeedButtonGroup.Title = 'Playback speed';
            app.x05Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x05Button.Text = 'x0.5';
            app.x05Button.Value = true;
            app.x1Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x1Button.Text = 'x1';
            app.x15Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x15Button.Text = 'x1.5';
            app.x2Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x2Button.Text = 'x2';
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo3.png');
            app.ParametersPanel = uipanel(app.LeftPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.CPIEditFieldLabel = uilabel(app.ParametersPanel);
            app.CPIEditFieldLabel.Text = 'CPI';
            app.CPIEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CPIEditField.Limits = [1 Inf];
            app.CPIEditField.ValueChangedFcn = createCallbackFcn(app, @CPIEditFieldValueChanged, true);
            app.CPIEditField.Value = 128;
            app.LoadFileButton = uibutton(app.ParametersPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Icon = fullfile(pathToMLAPP, 'icon_browse.png');
            app.LoadFileButton.Text = '';
            app.FilePathEditFieldLabel = uilabel(app.ParametersPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.ParametersPanel, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @DataFilePathChanged, true);
            app.FilePathEditField.ValueChangingFcn = createCallbackFcn(app, @DataFilePathChanged, true);
            app.HRRProfilesLabel = uilabel(app.ParametersPanel);
            app.HRRProfilesLabel.Text = 'HRR Profiles';
            app.HRRDropDown = uidropdown(app.ParametersPanel);
            app.HRRDropDown.ValueChangedFcn = createCallbackFcn(app, @HRRDropDownValueChanged, true);
            app.HRRProfilesLabel_2 = uilabel(app.ParametersPanel);
            app.HRRProfilesLabel_2.Text = 'PRI';
            app.PRIDropDown = uidropdown(app.ParametersPanel);
            app.PRIDropDown.ValueChangedFcn = createCallbackFcn(app, @PRIDropDownValueChanged, true);
            app.HRRProfilesLabel_3 = uilabel(app.ParametersPanel);
            app.HRRProfilesLabel_3.Text = 'Range Axis';
            app.RangeAxisDropDown = uidropdown(app.ParametersPanel);
            app.RangeAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @RangeAxisDropDownValueChanged, true);
            app.OverlapEditFieldLabel = uilabel(app.ParametersPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap %';
            app.OverlapEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.OverlapEditField.Limits = [0 100];
            app.OverlapEditField.RoundFractionalValues = 'on';
            app.OverlapEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapEditFieldValueChanged, true);
            app.OverlapEditField.Value = 50;
            app.CancelButton = uibutton(app.LeftPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'icon_cancel.png');
            app.CancelButton.Text = '';
            app.PausePlayback = uibutton(app.LeftPanel, 'push');
            app.PausePlayback.ButtonPushedFcn = createCallbackFcn(app, @PausePlaybackButtonPushed, true);
            app.PausePlayback.Icon = fullfile(pathToMLAPP, 'icon_pause.png');
            app.PausePlayback.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'background.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISAR_QLP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end