classdef QLP_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        ButtonGroup            matlab.ui.container.ButtonGroup
        Button3                matlab.ui.control.ToggleButton
        Button2                matlab.ui.control.ToggleButton
        Button                 matlab.ui.control.ToggleButton
        ReplayVideoButton      matlab.ui.control.Button
        OverlapEditField       matlab.ui.control.NumericEditField
        OverlapEditFieldLabel  matlab.ui.control.Label
        CPTWLEditField         matlab.ui.control.NumericEditField
        CPTWLEditFieldLabel    matlab.ui.control.Label
        GenerateImagesButton   matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        VideoFilePath = "ISARmovie.avi"; % Default video name
        ImagesGenerated = false; % Flag to track if images have been generated
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off'; % For axes 
            app.ReplayVideoButton.Enable = 'off'; % Initially disabled
            
        end

        % Button pushed function: GenerateImagesButton
        function ImageButtonPushed(app, event)
            % Disable the "Replay Video" button
            app.ReplayVideoButton.Enable = 'off';

            % Specify the path to your video file
            app.VideoFilePath = 'ISARmovieRAHaywood.avi';
        
            % Check if the video file exists
            if ~exist(app.VideoFilePath, 'file')
                uialert(app.UIFigure, 'Video file does not exist!', 'Error', 'Icon', 'error');
                return;
            end

            % Create a VideoReader object
            vidObj = VideoReader(app.VideoFilePath);
        
             % Read the first video frame to determine its size
            frame = readFrame(vidObj);
            frameSize = size(frame);
        
            % Create an axes for displaying the video with the same size as the frame
            app.UIAxes.Position = [0 0 frameSize(2)/1.8 frameSize(1)/1.8];
        
            % Read and display video frames
            while hasFrame(vidObj)
                    frame = readFrame(vidObj);
                    % Check if the video is paused
                    
                        imshow(frame, 'Parent', app.UIAxes);
                        drawnow; % This updates the display
            
                pause(0.01)
            end
        
            % Clean up the VideoReader object
            delete(vidObj);

            % Set the flag to indicate that images have been generated
            app.ImagesGenerated = true;
        
            % Enable the "Replay Video" button
            app.ReplayVideoButton.Enable = 'on';
          
        end

        % Button pushed function: ReplayVideoButton
        function ReplayButtonPushed(app, event)

            % Check if the video file exists
            if ~exist(app.VideoFilePath, 'file')
                uialert(app.UIFigure, 'Video file does not exist!', 'Error', 'Icon', 'error');
                return;
            end

            % Create a VideoReader object
            vidObj = VideoReader(app.VideoFilePath);
        
             % Read the first video frame to determine its size
            frame = readFrame(vidObj);
            frameSize = size(frame);
        
            % Create an axes for displaying the video with the same size as the frame
            app.UIAxes.Position = [0 0 frameSize(2)/1.8 frameSize(1)/1.8];
        
            % Read and display video frames
            while hasFrame(vidObj)
                    frame = readFrame(vidObj);
                    % Check if the video is paused
                    
                        imshow(frame, 'Parent', app.UIAxes);
                        drawnow; % This updates the display
                   
                pause(0.01)
            end
        
            % Clean up the VideoReader object
            delete(vidObj);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {211, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateImagesButton = uibutton(app.LeftPanel, 'push');
            app.GenerateImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.GenerateImagesButton.Text = 'Generate Images';
            app.CPTWLEditFieldLabel = uilabel(app.LeftPanel);
            app.CPTWLEditFieldLabel.Text = 'CPTWL';
            app.CPTWLEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CPTWLEditField.Limits = [1 Inf];
            app.CPTWLEditField.Value = 1;
            app.OverlapEditFieldLabel = uilabel(app.LeftPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap %';
            app.OverlapEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OverlapEditField.Limits = [0 100];
            app.OverlapEditField.RoundFractionalValues = 'on';
            app.ReplayVideoButton = uibutton(app.LeftPanel, 'push');
            app.ReplayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayVideoButton.Text = 'Replay Video';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QLP_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end