classdef ISAR_QLP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PausePlayback             matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        ParametersPanel           matlab.ui.container.Panel
        FilePathEditField         matlab.ui.control.EditField
        FilePathEditFieldLabel    matlab.ui.control.Label
        LoadFileButton            matlab.ui.control.Button
        OverlapEditField          matlab.ui.control.NumericEditField
        OverlapEditFieldLabel     matlab.ui.control.Label
        CPTWLEditField            matlab.ui.control.NumericEditField
        CPTWLEditFieldLabel       matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        PlaybackspeedButtonGroup  matlab.ui.container.ButtonGroup
        x2Button                  matlab.ui.control.ToggleButton
        x15Button                 matlab.ui.control.ToggleButton
        x1Button                  matlab.ui.control.ToggleButton
        x05Button                 matlab.ui.control.ToggleButton
        ReplayVideoButton         matlab.ui.control.Button
        GenerateButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data_file_path = "test.mat";
        VideoFilePath = "ISARmovie.avi"; % Default video name
        ImagesGenerated_flag = false; % Flag to track if images have been generated
        playback_speed = 0.1;  % Default speed value
        Cancel_flag = false;
        IsPaused = false; % Flag to track whether the video is paused
        
    end
    
    methods (Access = private)

        %% Image Contrast
        function [image_contrast] = imageContrast(image)
            % Calculate the image contrast (IC) of the input image. 
                % Uses Eq3 of M. Martorella and F. Berizzi, "Time windowing for highly 
                % focused isar image reconstruction," IEEE Transactions on Aerospace 
                % and Electronic Systems, vol. 41, no. 3, pp. 992–1007, 2005
            
            num_elements = numel(image);
            % Step 1: Calculate intensity = amplitude^2
            intensity = abs(image).^2;
            
            % Step 2: Calculate the a mean average power
            pixel_mean = sum(intensity,"all")/num_elements;
            
            % Step 3: Calculate the IC
            % difference and square
            numerator = sum((intensity - pixel_mean).^2,"all")/num_elements;
            % calculate contrast value
            image_contrast = sqrt(numerator)/pixel_mean;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initially disable buttons
            app.GenerateButton.Enable = 'off';
            app.CancelButton.Enable = 'off';
            app.ReplayVideoButton.Enable = 'off'; 
            app.x2Button.Enable = 'off';
            app.x15Button.Enable = 'off';
            app.x1Button.Enable = 'off';
            app.x05Button.Enable = 'off';

            % Set x1Button as the default selected button
            app.x1Button.Value = true;
     
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Enable Cancel button
            app.CancelButton.Enable = 'on';
            app.Cancel_flag = false;
            % Disable the buttons
            app.GenerateButton.Enable = 'off';
            app.ReplayVideoButton.Enable = 'off';
            app.x2Button.Enable = 'off';
            app.x15Button.Enable = 'off';
            app.x1Button.Enable = 'off';
            app.x05Button.Enable = 'off';
        
            % Check if the video file exists
            if ~exist(app.VideoFilePath, 'file')
                uialert(app.UIFigure, 'Video file does not exist!', 'Error', 'Icon', 'error');
                return;
            end

            % Create a VideoReader object
            vidObj = VideoReader(app.VideoFilePath);
            
            % Read and display video frames
            while hasFrame(vidObj)
                % Check if generation should be canceled
                if app.Cancel_flag
                    break; % Exit the loop if canceled
                end
                frame = readFrame(vidObj);
                app.Image.ImageSource = frame;
                pause(0.1)
            end
        
            % Clean up the VideoReader object
            delete(vidObj);

            % Set the flag to indicate that images have been generated
            app.ImagesGenerated_flag = true;
    
            % Enable the buttons
            app.GenerateButton.Enable = 'on';
            app.x2Button.Enable = 'on';
            app.x15Button.Enable = 'on';
            app.x1Button.Enable = 'on';
            app.x05Button.Enable = 'on';
            app.ReplayVideoButton.Enable = 'on';
            
            % Disable Cancel button
            app.CancelButton.Enable = 'off';
          
        end

        % Button pushed function: ReplayVideoButton
        function ReplayButtonPushed(app, event)
            % Enable pause
            app.PausePlayback.Enable = 'on';
            app.CancelButton.Enable = 'on';
            app.Cancel_flag = false;
            % Disable the buttons
            app.GenerateButton.Enable = 'off';
            app.ReplayVideoButton.Enable = 'off';
            app.x2Button.Enable = 'off';
            app.x15Button.Enable = 'off';
            app.x1Button.Enable = 'off';
            app.x05Button.Enable = 'off';

            % Check if the video file exists
            if ~exist(app.VideoFilePath, 'file')
                uialert(app.UIFigure, 'Video file does not exist!', 'Error', 'Icon', 'error');
                return;
            end

            % Create a VideoReader object
            vidObj = VideoReader(app.VideoFilePath);
      
            % Read and display video frames
            while hasFrame(vidObj)
                %Check if playback should be canceled
                if app.Cancel_flag
                    break; % Exit the loop if canceled
                end
                if app.IsPaused
                    % If paused, don't read new frames, just stay on the current frame
                    pause(0.1);
                else
                    frame = readFrame(vidObj);
                    app.Image.ImageSource = frame;
                    pause(1 * app.playback_speed)
                end
            end
        
            % Clean up the VideoReader object
            delete(vidObj);

            % Enable the buttons
            app.x2Button.Enable = 'on';
            app.x15Button.Enable = 'on';
            app.x1Button.Enable = 'on';
            app.x05Button.Enable = 'on';
            app.ReplayVideoButton.Enable = 'on';
            app.GenerateButton.Enable = 'on';
            % Disable pause
            app.PausePlayback.Enable = 'off';
            app.CancelButton.Enable = 'off';
        end

        % Selection changed function: PlaybackspeedButtonGroup
        function SpeedButtonPushed(app, event)
            % Determine which speed button is pressed and set the global 
            % speed variable accordingly
            if app.x05Button.Value
                app.playback_speed = 1;
            elseif app.x1Button.Value
                app.playback_speed = 0.5;
            elseif app.x15Button.Value
                app.playback_speed = 0.1;
            elseif app.x2Button.Value
                app.playback_speed = 0.005;
            end
        end

        % Callback function
        function DataFilePathChanged(app, event)

             % Get the entered file path
            filePath = app.FilePathEditField.Value;
        
            % Check if the entered file path is valid
            if ~exist(filePath, 'file')
                % Display an error message
                uialert(app.UIFigure, 'Invalid file path!', 'Error', 'Icon', 'error');
                % Disable the Generate button
                app.GenerateButton.Enable = 'off';
            else
                % Enable the Generate button
                app.GenerateButton.Enable = 'on';
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Cancel_flag = true;
        end

        % Button pushed function: PausePlayback
        function PausePlaybackButtonPushed(app, event)
            app.IsPaused = ~app.IsPaused; % Toggle the IsPaused flag
            if app.IsPaused
                app.PausePlayback.Icon = "./icon_play.png"; % Update button icon
            else
                app.PausePlayback.Icon = "./icon_pause.png"; % Update button icon
            end
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file,app.data_file_path] = uigetfile;
            app.FilePathEditField.Value = app.data_file_path;
            if(file~=0)
                % Enable the Generate button
                app.GenerateButton.Enable = 'on';
                 
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ReplayVideoButton = uibutton(app.LeftPanel, 'push');
            app.ReplayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayVideoButton.Text = 'Replay Video';
            app.PlaybackspeedButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlaybackspeedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.PlaybackspeedButtonGroup.Title = 'Playback speed';
            app.x05Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x05Button.Text = 'x0.5';
            app.x05Button.Value = true;
            app.x1Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x1Button.Text = 'x1';
            app.x15Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x15Button.Text = 'x1.5';
            app.x2Button = uitogglebutton(app.PlaybackspeedButtonGroup);
            app.x2Button.Text = 'x2';
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo3.png');
            app.ParametersPanel = uipanel(app.LeftPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.CPTWLEditFieldLabel = uilabel(app.ParametersPanel);
            app.CPTWLEditFieldLabel.Text = 'CPTWL';
            app.CPTWLEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CPTWLEditField.Limits = [1 Inf];
            app.CPTWLEditField.Value = 128;
            app.OverlapEditFieldLabel = uilabel(app.ParametersPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap %';
            app.OverlapEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.OverlapEditField.Limits = [0 100];
            app.OverlapEditField.RoundFractionalValues = 'on';
            app.OverlapEditField.Value = 50;
            app.LoadFileButton = uibutton(app.ParametersPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Icon = fullfile(pathToMLAPP, 'icon_browse.png');
            app.LoadFileButton.Text = '';
            app.FilePathEditFieldLabel = uilabel(app.ParametersPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.ParametersPanel, 'text');
            app.CancelButton = uibutton(app.LeftPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'icon_cancel.png');
            app.CancelButton.Text = '';
            app.PausePlayback = uibutton(app.LeftPanel, 'push');
            app.PausePlayback.ButtonPushedFcn = createCallbackFcn(app, @PausePlaybackButtonPushed, true);
            app.PausePlayback.Icon = fullfile(pathToMLAPP, 'icon_pause.png');
            app.PausePlayback.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'background.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISAR_QLP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end